classdef PulseEvolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        PulseGeneratorPanel           matlab.ui.container.Panel
        GridLayout33                  matlab.ui.container.GridLayout
        Panel_5                       matlab.ui.container.Panel
        GridLayout34                  matlab.ui.container.GridLayout
        LoadPulse3Button_h            matlab.ui.control.Button
        LoadPulse2Button_h            matlab.ui.control.Button
        LoadPulse1Button_h            matlab.ui.control.Button
        SaveasPulse3Button_h          matlab.ui.control.Button
        SaveasPulse2Button_h          matlab.ui.control.Button
        SaveasPulse1Button_h          matlab.ui.control.Button
        GENERATEButton_h              matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        GridLayout35                  matlab.ui.container.GridLayout
        mEditFieldLabel_h             matlab.ui.control.Label
        FFTpointspowerof2Label        matlab.ui.control.Label
        ntEditField_h                 matlab.ui.control.NumericEditField
        mEditField_h                  matlab.ui.control.NumericEditField
        ChirpCLabel                   matlab.ui.control.Label
        CEditField_h                  matlab.ui.control.NumericEditField
        InputpeakpowerP0WLabel        matlab.ui.control.Label
        P0EditField_h                 matlab.ui.control.NumericEditField
        WindowhalfwidthTmaxT0Label    matlab.ui.control.Label
        TmaxEditField_h               matlab.ui.control.NumericEditField
        InputhalfwidthT0psLabel       matlab.ui.control.Label
        T0EditField_h                 matlab.ui.control.NumericEditField
        TypeLabel                     matlab.ui.control.Label
        TypeDropDown_h                matlab.ui.control.DropDown
        NotePanel                     matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        NoteTextArea                  matlab.ui.control.TextArea
        CalculatingLamp_3             matlab.ui.control.Lamp
        CalculatingLamp_3Label        matlab.ui.control.Label
        PulseEvolutionPanel           matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        TODCheckBox_h                 matlab.ui.control.CheckBox
        stp_nvalLabel                 matlab.ui.control.Label
        StepNumberLabel               matlab.ui.control.Label
        dzvalueLabel                  matlab.ui.control.Label
        StepLengthkmLabel             matlab.ui.control.Label
        LDTValueLabel                 matlab.ui.control.Label
        TODispersionLengthL_DkmLabel  matlab.ui.control.Label
        NValueLabel                   matlab.ui.control.Label
        NParameterLabel               matlab.ui.control.Label
        zEditField_h                  matlab.ui.control.NumericEditField
        PropagatetozkmEditFieldLabel  matlab.ui.control.Label
        L_NLValueLabel                matlab.ui.control.Label
        NonlinearLengthL_NLkmLabel    matlab.ui.control.Label
        LDValueLabel                  matlab.ui.control.Label
        DispersionLengthL_DkmLabel    matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        Panel2                        matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        EXPORTButton                  matlab.ui.control.Button
        PROPAGATEButton               matlab.ui.control.Button
        ax1                           matlab.ui.control.UIAxes
        ax2                           matlab.ui.control.UIAxes
        FiberTab                      matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        DeltavalueLabel               matlab.ui.control.Label
        VParameterValueLabel          matlab.ui.control.Label
        RelativeIndexDifferenceLabel  matlab.ui.control.Label
        VParameterLabel               matlab.ui.control.Label
        k0valueLabel                  matlab.ui.control.Label
        k0m1Label                     matlab.ui.control.Label
        FiberGeneratorPanel           matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        FiberLengthkmEditField        matlab.ui.control.NumericEditField
        FiberLengthkmLabel            matlab.ui.control.Label
        UsedWavelengthmEditField      matlab.ui.control.NumericEditField
        UsedWavelengthmLabel          matlab.ui.control.Label
        DParameterpskmnmEditField     matlab.ui.control.NumericEditField
        DParameterpskmnmLabel         matlab.ui.control.Label
        AttenuationdBkmEditField      matlab.ui.control.NumericEditField
        AttenuationdBkmLabel          matlab.ui.control.Label
        NonlinearParameterW1kmEditField  matlab.ui.control.NumericEditField
        NonlinearParameterW1kmLabel   matlab.ui.control.Label
        TODParameter3ps3kmEditField   matlab.ui.control.NumericEditField
        TODParameter3ps3kmLabel       matlab.ui.control.Label
        GVDParameter2ps2kmEditField   matlab.ui.control.NumericEditField
        GVDParameter2ps2kmLabel_2     matlab.ui.control.Label
        CladdingIndexn2EditField      matlab.ui.control.NumericEditField
        CladdingIndexn2Label          matlab.ui.control.Label
        CoreIndexn1EditField          matlab.ui.control.NumericEditField
        CoreIndexn1Label              matlab.ui.control.Label
        CladdingRadiusbmEditField     matlab.ui.control.NumericEditField
        CladdingRadiusbmLabel         matlab.ui.control.Label
        CoreRadiusamEditField         matlab.ui.control.NumericEditField
        CoreRadiusamLabel             matlab.ui.control.Label
        AnalysisTab                   matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        PulseFreqChirpPanel           matlab.ui.container.Panel
        GridLayout23                  matlab.ui.container.GridLayout
        xSliderFreqChirp              matlab.ui.control.Slider
        axFreqChirp                   matlab.ui.control.UIAxes
        PulsePhasePanel               matlab.ui.container.Panel
        GridLayout22                  matlab.ui.container.GridLayout
        unwrapCheckBox                matlab.ui.control.CheckBox
        xSliderPhase                  matlab.ui.control.Slider
        axPulsePhase                  matlab.ui.control.UIAxes
        PulseSpecPowPanel             matlab.ui.container.Panel
        GridLayout21                  matlab.ui.container.GridLayout
        xSliderSpecPow                matlab.ui.control.Slider
        axSpecPow                     matlab.ui.control.UIAxes
        PulsePowerPanel               matlab.ui.container.Panel
        GridLayout20                  matlab.ui.container.GridLayout
        xSliderPow                    matlab.ui.control.Slider
        axPow                         matlab.ui.control.UIAxes
        PulseModPanel                 matlab.ui.container.Panel
        GridLayout19                  matlab.ui.container.GridLayout
        xSliderMod                    matlab.ui.control.Slider
        axPulseMod                    matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        GridLayout25                  matlab.ui.container.GridLayout
        CalculatingLamp               matlab.ui.control.Lamp
        CalculatingLampLabel          matlab.ui.control.Label
        LogPowerCheckBox_a            matlab.ui.control.CheckBox
        TODCheckBox_a                 matlab.ui.control.CheckBox
        NoteTextArea_2                matlab.ui.control.TextArea
        GridLayout11                  matlab.ui.container.GridLayout
        PropagateactivepulsePanel     matlab.ui.container.Panel
        GridLayout15                  matlab.ui.container.GridLayout
        ResetButton                   matlab.ui.control.Button
        PROPAGATE_2Button             matlab.ui.control.Button
        zEditField_a                  matlab.ui.control.NumericEditField
        zkmEditFieldLabel             matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        ComparePulsesPanel            matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        StopComparingButton           matlab.ui.control.Button
        zEditField_a_comp             matlab.ui.control.NumericEditField
        zkmLabel                      matlab.ui.control.Label
        Slider_compare                matlab.ui.control.Slider
        CompareButton                 matlab.ui.control.Button
        Pulse3CheckBox                matlab.ui.control.CheckBox
        Pulse2CheckBox                matlab.ui.control.CheckBox
        Pulse1CheckBox                matlab.ui.control.CheckBox
        FullEvolutionPanel_a          matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        FullEvoTabGroup_a             matlab.ui.container.TabGroup
        FullEvoTab_a                  matlab.ui.container.Tab
        GridLayout39                  matlab.ui.container.GridLayout
        Panel_7                       matlab.ui.container.Panel
        GridLayout42                  matlab.ui.container.GridLayout
        axBFactor_a                   matlab.ui.control.UIAxes
        axFullEvo_a                   matlab.ui.control.UIAxes
        SpecFullEvoTab_a              matlab.ui.container.Tab
        GridLayout40                  matlab.ui.container.GridLayout
        Panel_8                       matlab.ui.container.Panel
        GridLayout43                  matlab.ui.container.GridLayout
        axSpecBFactor_a               matlab.ui.control.UIAxes
        axSpecFullEvo_a               matlab.ui.control.UIAxes
        CalculateButton_a             matlab.ui.control.Button
        SolitonsTab                   matlab.ui.container.Tab
        GridLayout17                  matlab.ui.container.GridLayout
        GridLayout26                  matlab.ui.container.GridLayout
        GridLayout27                  matlab.ui.container.GridLayout
        CalculatingLamp_2             matlab.ui.control.Lamp
        CalculatingLamp_2Label        matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        SolitonEvolutionPanel         matlab.ui.container.Panel
        GridLayout_sol_evo            matlab.ui.container.GridLayout
        Panel_9                       matlab.ui.container.Panel
        GridLayout44                  matlab.ui.container.GridLayout
        axSpecBFactor_s               matlab.ui.control.UIAxes
        GridLayout36                  matlab.ui.container.GridLayout
        LogPowerCheckBox_s            matlab.ui.control.CheckBox
        TODCheckBox_s                 matlab.ui.control.CheckBox
        Panel_3                       matlab.ui.container.Panel
        GridLayout32                  matlab.ui.container.GridLayout
        axBFactor_s                   matlab.ui.control.UIAxes
        SpecFullEvoPanel_s            matlab.ui.container.Panel
        GridLayout31                  matlab.ui.container.GridLayout
        axSpecFullEvo_s               matlab.ui.control.UIAxes
        SpecPowerPanel_s              matlab.ui.container.Panel
        GridLayout30                  matlab.ui.container.GridLayout
        xSliderSpecPow_s              matlab.ui.control.Slider
        axSpecPower_s                 matlab.ui.control.UIAxes
        PowerPanel_s                  matlab.ui.container.Panel
        GridLayout29                  matlab.ui.container.GridLayout
        xSliderPow_s                  matlab.ui.control.Slider
        axPower_s                     matlab.ui.control.UIAxes
        FullEvoPanel_s                matlab.ui.container.Panel
        GridLayout28                  matlab.ui.container.GridLayout
        axFullEvo_s                   matlab.ui.control.UIAxes
        PropagateButton_s             matlab.ui.control.Button
        NoteTextArea_s                matlab.ui.control.TextArea
        SolitonOrderCalculatorPanel   matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        CEditField_s                  matlab.ui.control.NumericEditField
        CEditFieldLabel               matlab.ui.control.Label
        L_NLEditField_s               matlab.ui.control.NumericEditField
        NonlinearLengthL_NLkmLabel_2  matlab.ui.control.Label
        L_DEditField_s                matlab.ui.control.NumericEditField
        DispersionLengthL_DkmLabel_2  matlab.ui.control.Label
        T0EditField_s                 matlab.ui.control.NumericEditField
        T0psLabel_2                   matlab.ui.control.Label
        P0EditField_s                 matlab.ui.control.NumericEditField
        P0WLabel                      matlab.ui.control.Label
        NEditField_s                  matlab.ui.control.NumericEditField
        NEditFieldLabel               matlab.ui.control.Label
        beta2EditField_s              matlab.ui.control.NumericEditField
        GVDParameter2ps2kmLabel       matlab.ui.control.Label
        gammaEditField_s              matlab.ui.control.NumericEditField
        NonlinearParametrerW1kmLabel  matlab.ui.control.Label
        GuideTab                      matlab.ui.container.Tab
        GridLayout13                  matlab.ui.container.GridLayout
        FontSizeEditField             matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel        matlab.ui.control.Label
        InfoPanel                     matlab.ui.container.Panel
        GridLayout49                  matlab.ui.container.GridLayout
        TextArea_6                    matlab.ui.control.TextArea
        SolitonsPanel                 matlab.ui.container.Panel
        GridLayout48                  matlab.ui.container.GridLayout
        TextArea_5                    matlab.ui.control.TextArea
        HomePanel                     matlab.ui.container.Panel
        GridLayout45                  matlab.ui.container.GridLayout
        TextArea_4                    matlab.ui.control.TextArea
        AnalysisPanel                 matlab.ui.container.Panel
        GridLayout47                  matlab.ui.container.GridLayout
        TextArea                      matlab.ui.control.TextArea
        FiberPanel                    matlab.ui.container.Panel
        GridLayout46                  matlab.ui.container.GridLayout
        TextArea_3                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        fiber; %fibra ottica (classe Fiber)
        pulse; %impulso "attivo" (classe Pulse)
        
        TOD_c; %"TOD_on", "TOD_off"; abilita la TOD
        uw_c ; %"uw_on", "uw_off"; abilita l'unwrap della fase
        
        
        L_D;   %Lunghezza di dispersione
        L_DT;  %Lunghezza di dispersione del terzo ordine
        L_NL;  %Lunghezza di nonlinearità


        N;     %Parametro N
        NT;    %Parametro N' (da sola TOD)
        
        step_num; %numero di passi dell'algoritmo 
        deltaz;   %lunghezza passo
        L;        %distanza di propagazione
        L_c;      %distanza di propagazione, usata nel confronto
        
        plotCounter; %conta il numero di plot eseguiti
        
        %impulsi usati per il confronto
        pulse_1;
        pulse_2;
        pulse_3;

        %parametri per la realizzazione dell'impuslo 
        T0    %semilarghezza temporale dell'impulso a intensità 1/e
        Tmax  %larghezza finestra (in T0)
        C     %parametro di chirp
        m     %parametro di pendenza per impulsi super gaussiani
        P0    %picco di potenza iniziale
        type  %'g': super Gaussiana, 's' a secante iperbolica
        nt    %numero di campioni usati nella FFT
        
        
        note = ""; %avvisi per l'utente
        
    end
    
    methods (Access = private)
        %Questo metodo aggiorna le caratteristiche della fibra (fiber)
        %usata nella simulazione.
        function resultFiber = updateFiber(app)
            
            app.lightNote('y');
            
            %aggiorno le caratteristiche della fibra
            app.fiber.lambda = app.UsedWavelengthmEditField.Value;
            app.fiber.a      = app.CoreRadiusamEditField.Value;
            app.fiber.b      = app.CladdingRadiusbmEditField.Value;
            app.fiber.n1     = app.CoreIndexn1EditField.Value;
            app.fiber.nc     = app.CladdingIndexn2EditField.Value;
            app.fiber.alpha  = app.AttenuationdBkmEditField.Value;
            app.fiber.beta2  = app.GVDParameter2ps2kmEditField.Value;
            app.fiber.beta3  = app.TODParameter3ps3kmEditField.Value;
            app.fiber.gamma  = app.NonlinearParameterW1kmEditField.Value;
            app.fiber.length = app.FiberLengthkmEditField.Value;
            
            %aggiorno i valori nell'app
            app.k0valueLabel.Text                  = num2str(app.fiber.get_k0());
            app.DeltavalueLabel.Text               = num2str(app.fiber.getDelta());
            app.VParameterValueLabel.Text          = num2str(app.fiber.get_V());
            app.DParameterpskmnmEditField.Value    = app.fiber.get_D();
            
            %output
            resultFiber = app.fiber;
            app.updateNote("Fiber properties changed.")
            app.lightNote('w');
        end

        
        %Questo metodo aggiorna le caratteristiche dell'impulso attivo
        %usato nella simulazione e aggiorna i grafici necessari a
        %rappresentarlo.
        function resultPulse = updatePulse(app)
            %app.lightNote('y');
            %reset assi
            holdAX('off',app.ax1,app.ax2)                 
            holdAX('off',app.axPow,app.axSpecPow);
            holdAX('off',app.axPulseMod,app.axPulsePhase); 
            hold(app.axFreqChirp, 'off'); 
            holdAX('off',app.axBFactor_a, app.axFullEvo_a);
            holdAX('off',app.axSpecBFactor_a,app.axSpecFullEvo_a);
            holdAX('off',app.axPower_s,app.axSpecPower_s); 
            holdAX('off',app.axBFactor_s, app.axFullEvo_s);
            holdAX('off',app.axSpecFullEvo_s, app.axSpecBFactor_s);
            
              
            app.pulse = Pulse(app.C,app.m,app.P0,app.T0,app.Tmax,app.type, app.nt);
            %aggiorno i grafici utilizzati
            app.pulse.plotPulsePower(app.ax1,app.ax2,[0,0,0],'z = 0 km');
            app.pulse.plotPulsePower(app.axPow,app.axSpecPow,[0,0,0],'z = 0 km');
            app.pulse.plotPulsePower(app.axPower_s,app.axSpecPower_s,[0,0,0],'z = 0 km ');
            app.pulse.plotPulseModPhase(app.axPulseMod,app.axPulsePhase, [0 ,0 ,0], 'z=0 km', app.uw_c);
            app.pulse.plotPulseFreqChirp(app.axFreqChirp, [0 ,0 ,0], 'z=0 km');
            %svuoto i grafici non utilizzati
            waterfall(app.axFullEvo_a, NaN, NaN,NaN);
            waterfall(app.axSpecFullEvo_a, NaN, NaN,NaN);
            plot(app.axBFactor_a, NaN, NaN);
            plot(app.axSpecBFactor_a,NaN,NaN);
            waterfall(app.axFullEvo_s, NaN, NaN,NaN);
            plot(app.axBFactor_s, NaN, NaN);
            plot(app.axSpecBFactor_s, NaN, NaN);
            waterfall(app.axSpecFullEvo_s, NaN, NaN,NaN);
            
            %elimino eventuali legende non volute
            legend(app.axBFactor_a, 'off');
            legend(app.axSpecBFactor_a, 'off');
            legend(app.axBFactor_s, 'off');
            legend(app.axSpecBFactor_s, 'off');
            
            
            %permanenza grafici
            holdAX('on',app.ax1,app.ax2);
            holdAX('on',app.axPow,app.axSpecPow);
            holdAX('on',app.axPulseMod,app.axPulsePhase);
            hold(app.axFreqChirp, 'on');
            holdAX('on',app.axBFactor_a, app.axFullEvo_a);
            holdAX('on',app.axSpecBFactor_a,app.axSpecFullEvo_a);
            holdAX('on',app.axPower_s,app.axSpecPower_s);
            holdAX('on',app.axBFactor_s, app.axFullEvo_s);
            holdAX('on',app.axSpecFullEvo_s, app.axSpecBFactor_s);
            
            %output
            resultPulse = app.pulse;
            app.updateNote('Pulse generated');
             %app.lightNote('w');
            
        end 
        
        
        %Questo metodo aggiorna l'interfaccia utente.
        function updateUi(app)
            %app.lightNote('y');
            %calcolo la lunghezza di dispersione
            app.L_D = getL_D(app.pulse,app.fiber);
            app.LDValueLabel.Text = num2str(app.L_D);
            
            %calcolo la lunghezza di dispersione (TOD)
            app.L_DT = getL_DT(app.pulse,app.fiber);
            app.LDTValueLabel.Text = num2str(app.L_DT);
            
            %calcolo la lunghezza di nonlinearità
            app.L_NL = getL_NL(app.pulse,app.fiber);
            app.L_NLValueLabel.Text = num2str(app.L_NL);
            
            %calcolo il parametro N
            app.N = getN(app.pulse,app.fiber);
            app.NValueLabel.Text = num2str(app.N);
            app.NT = getNT(app.pulse, app.fiber);
            
            %calcolo il numero di passi 
            if app.L_NL < 1         
                app.step_num = round(40*app.L/app.L_NL);
            else
                app.step_num = round(40*app.L);
            end
            
            if app.step_num < 40
                app.step_num = 40;
            end
            
            if app.step_num > 20000
                app.step_num = 20000;
            end
            
            app.stp_nvalLabel.Text = num2str(app.step_num);
                    
	
        	%calcolo la lunghezza di un passo (km)
            app.deltaz  = app.L/app.step_num;
            app.dzvalueLabel.Text = num2str(app.deltaz);
            
            %aggiorno gli slider e le caselle che controllano la propagazione
            app.Slider.Limits         = [0,app.fiber.length];
            app.Slider_2.Limits       = [0,app.fiber.length];
            app.Slider_compare.Limits = [0,app.fiber.length];
            
            app.zEditField_h.Limits      = [0,app.fiber.length];
            app.zEditField_a.Limits      = [0,app.fiber.length];
            app.zEditField_a_comp.Limits = [0,app.fiber.length];
            
            app.L  = app.Slider.Value;
            app.L_c = app.Slider_compare.Value;
                       
            %aggiorno i valori mostrati nella Home
            app.P0EditField_h.Value        = app.pulse.P0;
            app.T0EditField_h.Value        = app.pulse.T0;
            app.mEditField_h.Value         = app.pulse.m;
            app.CEditField_h.Value         = app.pulse.C;
            app.ntEditField_h.Value        = app.pulse.nt;
            app.TmaxEditField_h.Value      = app.pulse.Tmax;
            if strcmp(app.pulse.type,'g')
                app.TypeDropDown_h.Value = "Super Gaussian";
                app.mEditField_h.Visible='on';
            else
                app.TypeDropDown_h.Value = "Hyperbolic Secant";
                app.mEditField_h.Visible='off';
            end
            
            %aggiorno i valori mostrati nel pannello Solitions
            app.beta2EditField_s.Value = app.fiber.beta2;
            app.gammaEditField_s.Value = app.fiber.gamma;            app.NEditField_s.Value     = app.N;
            app.L_DEditField_s.Value   = app.L_D;
            app.L_NLEditField_s.Value  = app.L_NL;
            app.P0EditField_s.Value    = app.pulse.P0;
            app.T0EditField_s.Value    = app.pulse.T0;
            app.CEditField_s.Value     = app.pulse.C;
            
            %aggiorno gli slider degli assi 
            app.xSliderPow.Limits      = [0.0001, app.axPow.XLim(2)];
            app.xSliderSpecPow.Limits  = [0.0001, app.axSpecPow.XLim(2)];
            app.xSliderFreqChirp.Limits= [0.0001, app.axFreqChirp.XLim(2)];
            app.xSliderMod.Limits      = [0.0001, app.axPulseMod.XLim(2)];
            app.xSliderPhase.Limits    = [0.0001, app.axPulsePhase.XLim(2)];
            app.xSliderPow_s.Limits    = [0.0001, app.axPower_s.XLim(2)];
            app.xSliderSpecPow_s.Limits= [0.0001, app.axSpecPower_s.XLim(2)];
            
            app.xSliderPow.MajorTicksMode        = "manual";
            app.xSliderSpecPow.MajorTicksMode    = "manual";
            app.xSliderFreqChirp.MajorTicksMode  = "manual";
            app.xSliderMod.MajorTicksMode        = "manual";
            app.xSliderPhase.MajorTicksMode      = "manual";
            app.xSliderPow_s.MajorTicksMode      = "manual";
            app.xSliderSpecPow_s.MajorTicksMode  = "manual";
 
            app.xSliderPow.MajorTicks      = [0.1 : app.axPow.XLim(2)/5        : app.axPow.XLim(2)];
            app.xSliderSpecPow.MajorTicks  = [0.1 : app.axSpecPow.XLim(2)/5    : app.axSpecPow.XLim(2)];
            app.xSliderFreqChirp.MajorTicks= [0.1 : app.axFreqChirp.XLim(2)/5  : app.axFreqChirp.XLim(2)];
            app.xSliderMod.MajorTicks      = [0.1 : app.axPulseMod.XLim(2)/5   : app.axPulseMod.XLim(2)];
            app.xSliderPhase.MajorTicks    = [0.1 : app.axPulsePhase.XLim(2)/5 : app.axPulsePhase.XLim(2)];
            app.xSliderPow_s.MajorTicks    = [0.1 : app.axPower_s.XLim(2)/5    : app.axPower_s.XLim(2)];
            app.xSliderSpecPow_s.MajorTicks= [0.1 : app.axSpecPower_s.XLim(2)/5: app.axSpecPower_s.XLim(2)];
            
            app.xSliderPow.Value       = app.axPow.XLim(2);
            app.xSliderSpecPow.Value   = app.axSpecPow.XLim(2);
            app.xSliderFreqChirp.Value = app.axFreqChirp.XLim(2);
            app.xSliderMod.Value       = app.axPulseMod.XLim(2);
            app.xSliderPhase.Value     = app.axPulsePhase.XLim(2);
            app.xSliderPow_s.Value     = app.axPower_s.XLim(2);
            app.xSliderSpecPow_s.Value = app.axSpecPower_s.XLim(2);
            
            %app.lightNote('w');
        end
        
        
        %aggiorna tutto
        function  updateAll(app)
            app.updateFiber();
            app.updatePulse();
            app.updateUi()
        end
        
        
        %Questa funzione permette di impostare come "impulso attivo" uno
        %degli impulsi salvati in memoria.
        function loadedPulse = loadPulse(app,p)
            %p è un impuslo in memoria
            
            app.lightNote('y');
            
            %Carico i parametri per costruire un nuovo impulso 
            if strcmp(p.type,'g')
                app.type = 'g';
            else
                app.type = 's';
            end
            app.Tmax = p.Tmax;
            app.m    = p.m;
            app.C    = p.C;
            app.T0   = p.T0;
            app.P0   = p.P0;
            app.nt   = p.nt;
        
            app.updatePulse(); %genero l'impulso 
            app.updateUi();    %aggiorno l'interfaccia 
  
            loadedPulse = app.pulse;
            
            app.lightNote('w');
        end
        
        %Questa funzione permette di confrontare fino a tre impulsi nella
        %pagina di analisi del programma.
        function comparePulses(app)
            
            app.lightNote('y');
            
            %reset assi
            hold(app.axBFactor_a,"off");
            hold(app.axSpecBFactor_a,'off');
            hold(app.axFreqChirp, "off");
            hold(app.axPow, "off");
            hold(app.axSpecPow, "off");
            hold(app.axPulseMod, "off");
            hold(app.axPulsePhase, "off");
            hold(app.axFullEvo_a, "off");
            hold(app.axSpecFullEvo_a, "off");
            
            
            plot(app.axFullEvo_a, NaN, NaN);
            plot(app.axSpecFullEvo_a, NaN, NaN)
            
            %comparo gli impulsi 
            app.updateNote("Comparing pulse, please wait...");
            if app.Pulse1CheckBox.Value == 1
                app.updateNote("...simulating pulse 1...");
                %plotto i valori riferiti a pulse_1
                app.plot2comp(app.pulse_1,'r',"pulse 1");
            end
            
            if app.Pulse2CheckBox.Value == 1
                app.updateNote("...simulating pulse 2...");
                %plotto i valori riferiti a pulse_2
                app.plot2comp(app.pulse_2,'g',"pulse 2");
            end
            
            if app.Pulse3CheckBox.Value == 1
                app.updateNote("...simulating pulse 3...");
                %plotto i valori riferiti a pulse_3
                app.plot2comp(app.pulse_3,'b',"pulse 3");
            end
            
            app.updateNote("...comparison completed.");
            %reset assi
            hold(app.axBFactor_a,"off");
            hold(app.axSpecBFactor_a,'off');
            hold(app.axFreqChirp, "off");
            hold(app.axPow, "off");
            hold(app.axSpecPow, "off");
            hold(app.axPulseMod, "off");
            hold(app.axPulsePhase, "off");
            
            
            app.lightNote('w');
        end
        
        %Questo metodo è utilizzato all'interno di comparePulses. 
        %L'impulso da plottare viene normalizzato rispetto a app.pulse_1.
        function plot2comp(app, pulse, color,name)
            %plotto i valori riferiti agli impulsi selezionati 
            
            app.lightNote('y');
            
            %salvo i parametri dell'impulso per recuperarli successivamente
            stored_T0   = pulse.T0;
            stored_Tmax = pulse.Tmax;
            stored_P0   = pulse.P0;
            
        
            %reset impulsi
            pulse = Pulse(pulse.C,pulse.m,pulse.P0,pulse.T0,pulse.Tmax,pulse.type);
            
            %normalizzo rispetto a pulse_1
            pulse.Tmax  = pulse.Tmax*pulse.T0/app.pulse_1.T0; 
            pulse.dtau  = pulse.dtau*pulse.T0/app.pulse_1.T0;
            pulse.tau   = pulse.tau*pulse.T0/app.pulse_1.T0;
            pulse.omega = pulse.omega*app.pulse_1.T0/pulse.T0;
            pulse.Uz = pulse.Uz*sqrt(pulse.P0/app.pulse_1.P0);
            pulse.Uz_spectrum = pulse.Uz_spectrum*(pulse.P0)/app.pulse_1.P0;
            
            
            if app.L_c == 0
                %valori iniziali    
            else
                %evoluzione
                pulse.SSFM(app.fiber,app.L_c,app.TOD_c)
            end
            
            %plot
            
            pulse.plotPulsePower(app.axPow,app.axSpecPow,color,strcat(name,' z=',num2str(app.L_c), ' km'));
            pulse.plotPulseModPhase(app.axPulseMod,app.axPulsePhase,color,strcat(name,' z=',num2str(app.L_c), ' km'), app.uw_c);
            pulse.plotPulseFreqChirp(app.axFreqChirp,color,strcat(name,' z=',num2str(app.L_c), ' km'));
            
            %ora utilizzo la normalizzazione iniziale
            pulse = Pulse(pulse.C,pulse.m,stored_P0,stored_T0,stored_Tmax,pulse.type);

            %fattore di allargamento
            [z,bFactor,s_bFactor] = getBFactor(pulse,app.fiber,app.TOD_c,100);
            %allargamento temporale
            plot(app.axBFactor_a, z, bFactor, color,"DisplayName", name);
            legend(app.axBFactor_a,"Location", "best");
            app.axBFactor_a.XLimMode = 'auto';
            app.axBFactor_a.YLimMode = 'auto';
            app.axBFactor_a.Toolbar = axtoolbar(app.axBFactor_a,'default');
            %allargamento spettrale
            plot(app.axSpecBFactor_a, z, s_bFactor, color,"DisplayName", name);
            legend(app.axSpecBFactor_a,"Location", "best");
            app.axSpecBFactor_a.XLimMode = 'auto';
            app.axSpecBFactor_a.YLimMode = 'auto';
            app.axSpecBFactor_a.Toolbar = axtoolbar(app.axSpecBFactor_a,'default');
            
            
            hold(app.axBFactor_a,"on");
            hold(app.axSpecBFactor_a,'on');
            hold(app.axFreqChirp, "on");
            hold(app.axPow, "on");
            hold(app.axSpecPow, "on");
            hold(app.axPulseMod, "on");
            hold(app.axPulsePhase, "on");


            pulse.T0   = stored_T0;
            pulse.Tmax = stored_Tmax;
            pulse.P0   = stored_P0;  
            
            app.lightNote('w');
        end
        
        %Realizza note per l'utente
        function updateNote(app, text)
            app.note = app.note + newline + text;
            app.NoteTextArea.Value = app.note;
            app.NoteTextArea_2.Value = app.note;
            app.NoteTextArea_s.Value = app.note;
            pause(0.01);
            scroll(app.NoteTextArea, 'bottom');
            scroll(app.NoteTextArea_2, 'bottom');
            scroll(app.NoteTextArea_s, 'bottom');
            
        end
        
        
        %Modifica il limite dell'asse x di ax tramite uno slider.
        function changeXLim(~, slider,ax)
            xMax    = slider.Value;
            ax.XLim = [-xMax, xMax];
        end
        
        %Cambia colore ad una spia luminosa nell'interfaccia.
        function lightNote(app, c, l)
            %c : color
            %l : label
            app.CalculatingLamp.Color   = c;
            app.CalculatingLamp_2.Color = c;
            app.CalculatingLamp_3.Color = c;
            if nargin > 2
              app.CalculatingLampLabel.Text   = l;
              app.CalculatingLamp_2Label.Text = l;
              app.CalculatingLamp_3Label.Text = l;
            end
        end
        
        %Regola la dimensione dei font all'interno dell'pannello panel
        function resizeFont(app, container, font_size)
            %container: pannello o griglia
            
            %regolo la dimensione del titolo del contenitore
            try container.FontSize  = font_size;
            catch   
            end
             
            %ottengo i figli
            child = allchild(container);
            
            %aggiusto il font dei figli 
            for i = 1 : length(child)
                
                if isa(child(i),'matlab.ui.container.GridLayout')
                   %l'oggetto è una griglia -> ricorsione
                   resizeFont(app,child(i), font_size);
                   disp("grid found")
                elseif isa(child(i),'matlab.ui.container.Panel')
                   %l'oggetto è un pannello -> ricorsione
                   resizeFont(app,child(i), font_size);
                   disp("panel found")
                elseif  isa(child(i),'matlab.ui.container.TabGroup')
                   %l'oggetto è una tab -> ricorsione
                   resizeFont(app,child(i), font_size);
                   disp("tab found")
                elseif  isa(child(i),'matlab.ui.container.Tab')
                   %l'oggetto è una tab -> ricorsione
                   resizeFont(app,child(i), font_size);
                   disp("tab found")
                else
                   %l'oggetto non è un contenitore
                   
                   if strcmp(child(i).Tag, "xSlider")
                       %l'oggetto e' uno slider per l'asse x
                       child(i).FontSize = round(font_size/2);
                   else
                       %oggetto qualsiasi
                       try 
                           
                           child(i).FontSize = font_size;
                           if isa(child(i),'matlab.ui.control.UIAxes')
                              child(i).Legend.FontSize = round(font_size*08);
                              disp("Legend edited");
                           end
                           
                           disp("mod");
                       catch   
                            %FontSize not configurabile
                            disp("font not adjustable");
                       end
                   end 
                end
            end  
        end
        
        
        %Abilita o disabilita la dispersione del terzo ordine, a seconda
        %del valore di un check box,
        %aggiorna l'interfaccia di conseguenza.
        function TOD_check_box_update(app, check_box)
            if check_box.Value == 1 
                app.TOD_c = "TOD_on"; 
            else
                app.TOD_c = "TOD_off";
            end
            
            if strcmp(app.TOD_c,"TOD_on")
                app.TODCheckBox_h.Value = 1;
                app.TODCheckBox_a.Value = 1;
                app.TODCheckBox_s.Value = 1;
                app.updateNote("TOD on");
            else
                app.TODCheckBox_h.Value = 0;
                app.TODCheckBox_a.Value = 0;
                app.TODCheckBox_s.Value = 0;
                app.updateNote("TOD off");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, font_size)
            
            app.lightNote('y');
            
            
            %inizializzo i parametri iniziali dell'impulso 
            app.T0   = app.T0EditField_h.Value;
            app.Tmax = app.TmaxEditField_h.Value;
            app.C    = app.CEditField_h.Value;
            app.m    = app.mEditField_h.Value;
            app.P0   = app.P0EditField_h.Value;
            app.nt   = app.ntEditField_h.Value;
            if strcmp(app.TypeDropDown_h.Value, 'Super Gaussian')
                app.type = "g";
                app.mEditField_h.Visible='on';
                app.mEditFieldLabel_h.Visible='on';
            else
                
                app.mEditField_h.Visible='off';
                app.mEditFieldLabel_h.Visible='off';
                app.type = "s";
            end
            
            %inizializzo fibra e impulso 
            app.fiber = Fiber();
            app.pulse = Pulse();
            
            %imposto i valori iniziali delle distanze di propagazione
            app.L              = app.Slider.Value;
            app.Slider_2.Value = app.Slider.Value;
            app.L_c            = app.Slider_compare.Value;
             
            %phase unwrap 
            if app.unwrapCheckBox.Value == 1
                app.uw_c = "uw_on";
            else 
                app.uw_c = "uw_off";
            end
            
            %update
            app.updateAll();

            %gli impulsi usati per i confronti sono inizialmente uguali
            app.pulse_1 = app.pulse;
            app.pulse_2 = app.pulse;
            app.pulse_3 = app.pulse;
            
            %Apertura in modalità schermo intero 
            app.UIFigure.WindowState = 'maximized';
            
            %Scelta del pannello da visualizzare nell'analisi
            app.FullEvoTabGroup_a.SelectedTab = app.FullEvoTab_a;
            
            %Regola la dimensione del font se richiesta
            if nargin > 1 
                %regolo la dimensione dei font di alcuni pannelli 
                app.resizeFont(app.TabGroup, font_size);
                app.FontSizeEditField.Value = font_size;
            end
            
            %Dispersione terzo ordine 
            app.TOD_check_box_update(app.TODCheckBox_h);
            
            %Avviso l'utente che il programma è pronto
            app.lightNote('w');
            app.updateNote("Ready.");


        end

        % Value changed function: UsedWavelengthmEditField
        function UsedWavelengthmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi();
        end

        % Value changed function: AttenuationdBkmEditField
        function AttenuationdBkmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi(); 
        end

        % Value changed function: NonlinearParameterW1kmEditField
        function NonlinearParameterW1kmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi();
        end

        % Value changed function: TODParameter3ps3kmEditField
        function TODParameter3ps3kmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi();
        end

        % Value changed function: GVDParameter2ps2kmEditField
        function GVDParameter2ps2kmEditFieldValueChanged(app, event)

            app.updateAll()
            
            %controllo la TOD 
            if app.fiber.beta2==0
                app.TODCheckBox_h.Value     = 1;
                app.TOD_check_box_update(app.TODCheckBox_h);
                
                app.TODCheckBox_h.Enable  = 'off';
                app.TODCheckBox_a.Enable  = 'off';
                app.TODCheckBox_s.Enable  = 'off';  
            else
                app.TODCheckBox_h.Value  = 1;
                app.TOD_check_box_update(app.TODCheckBox_h);
                app.TODCheckBox_h.Enable = 'on';
                app.TODCheckBox_a.Enable = 'on';
                app.TODCheckBox_s.Enable = 'on';
            end
            
        end

        % Value changed function: CladdingIndexn2EditField
        function CladdingIndexn2EditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi()
        end

        % Value changed function: CoreIndexn1EditField
        function CoreIndexn1EditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi()
        end

        % Value changed function: CladdingRadiusbmEditField
        function CladdingRadiusbmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi()
        end

        % Value changed function: CoreRadiusamEditField
        function CoreRadiusamEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi()
        end

        % Value changed function: DParameterpskmnmEditField
        function DParameterpskmnmEditFieldValueChanged(app, event)
            %se si imposta D, beta2 cambia di conseguenza
            D = app.DParameterpskmnmEditField.Value;
            app.fiber.beta2                       = app.fiber.convDtoBeta2(D);
            app.GVDParameter2ps2kmEditField.Value = app.fiber.beta2;
            app.updateFiber();
            app.updateUi()
            
        end

        % Value changed function: TypeDropDown_h
        function TypeDropDown_hValueChanged(app, event)
            if strcmp(app.TypeDropDown_h.Value, 'Super Gaussian')
                app.type = "g";
                app.mEditField_h.Visible='on';
                app.mEditFieldLabel_h.Visible ='on';
            else
                app.mEditField_h.Visible='off';
                app.mEditFieldLabel_h.Visible ='off';
                app.type = "s";
            end
            
        end

        % Value changed function: FiberLengthkmEditField
        function FiberLengthkmEditFieldValueChanged(app, event)
            app.updateFiber();
            app.updateUi()
        end

        % Button pushed function: PROPAGATEButton
        function PROPAGATEButtonPushed(app, event)
            %Simulazione della propagazione dell'impulso attivo
            app.updateNote("Calculating, please wait.")
            app.lightNote('y');
            
            %Split step fourier method
            SSFM(app.pulse,app.fiber,app.L,app.TOD_c);
            
            %plot: più l'impulso vine fatto propagare vicino alla fine
            %del collegamento, più il grafico diventa chiaro.
            app.plotCounter = app.plotCounter+1;
            redTone = 1/app.fiber.length*app.L;
            color = [redTone,0,0];
            
            app.pulse.plotPulsePower(app.ax1,app.ax2, color,strcat('z = ',num2str(app.L), ' km'));
            app.pulse.plotPulsePower(app.axPow,app.axSpecPow,color,strcat('z = ',num2str(app.L), ' km'));
            app.pulse.plotPulseModPhase(app.axPulseMod,app.axPulsePhase,color,strcat('z = ',num2str(app.L), ' km'), app.uw_c);
            app.pulse.plotPulseFreqChirp(app.axFreqChirp,color,strcat('z = ',num2str(app.L), ' km'));
                
            app.updateNote("Pulse propagated.")
            app.lightNote('w');
        end

        % Value changed function: zEditField_h
        function zEditField_hValueChanged(app, event)
            app.Slider.Value = app.zEditField_h.Value;
            app.L = app.Slider.Value;
            app.Slider_2.Value = app.zEditField_h.Value;
            app.zEditField_a.Value = app.L;
            app.updateUi()
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.zEditField_h.Value = event.Value;            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.L = app.Slider.Value;
            app.Slider_2.Value = app.L;
            app.zEditField_a.Value = app.L;
            app.updateUi()
           
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            %Alcuni dati usati nell'applicazione vengono esportati in un
            %file ".mat"
            app.lightNote('y');
            app.updateNote('Exporting Data, please wait...')
            pulse         = app.pulse;    %impulso attivo
            pulse_1       = app.pulse_1;
            pulse_2       = app.pulse_2;
            pulse_3       = app.pulse_3;
            
            fiber         = app.fiber;    %fibra
            
            %evoluzione dell'impulso attivo
            [z_fe, powUz_fe, spec_powUz_fe] = app.pulse.evolve(app.fiber, app.TOD_c,11);
            app.updateNote('...');
            %allargamento dell'impulso attivo
            [z_bf, bFactor, spec_bFactor] = app.pulse.getBFactor(app.fiber, app.TOD_c,100);
            uisave({'pulse','pulse_1','pulse_2', 'pulse_3','fiber', ...
                    'z_fe','powUz_fe', 'spec_powUz_fe', ...
                    'z_bf', 'bFactor', 'spec_bFactor'},'simulation 1');
            app.updateNote("Data correctly exported!")
        end

        % Button down function: AnalysisTab
        function AnalysisTabButtonDown(app, event)
            app.updateNote("Active pulse analysis or compare up to 3 pulses. Change active pulse in 'Home'.")
        end

        % Button pushed function: CalculateButton_a
        function CalculateButton_aPushed(app, event)
            %Questo metodo permette di visualizzare l'evoluzione 
            %dell'impulso attivo lungo tutta la fibra nella pagina di analisi 
            
            app.lightNote('y');
            %reset assi
            holdAX('off', app.axFullEvo_a,app.axBFactor_a);
            holdAX('off', app.axSpecFullEvo_a, app.axSpecBFactor_a)
            waterfall(app.axFullEvo_a, NaN,NaN,NaN);
            waterfall(app.axSpecFullEvo_a, NaN,NaN,NaN);
            plot(app.axBFactor_a,NaN,NaN);
            plot(app.axSpecBFactor_a, NaN,NaN);
            
            %simulo l'evoluzione 
            app.updateNote("Calculating active pulse full evolution, please wait...")
            [z_fe,powUz_fe,powUz_spec_fe] = evolve(app.pulse,app.fiber,app.TOD_c,11);
          
            %plot della potenza in un grafico a cascata
            waterfall(app.axFullEvo_a, app.pulse.tau, z_fe,powUz_fe);
            xlabel(app.axFullEvo_a, 'T/T0');
            ylabel(app.axFullEvo_a, 'distance (km)');
            zlabel(app.axFullEvo_a, 'Intensity');
            
            %plot della potenza spettrale in un grafico a cascata
            waterfall(app.axSpecFullEvo_a,  fftshift(app.pulse.omega)/(2*pi), z_fe,powUz_spec_fe);
            xlabel(app.axSpecFullEvo_a, 'Norm. frequency');
            ylabel(app.axSpecFullEvo_a, 'distance (km)');
            zlabel(app.axSpecFullEvo_a, 'Intensity');
            
            %allargamento (100 campioni)
            [z,bFactor, s_bFactor] = getBFactor(app.pulse,app.fiber,app.TOD_c,100);
            %allargamento temporale
            plot(app.axBFactor_a, z, bFactor, 'k-', "DisplayName", "active pulse");
            legend(app.axBFactor_a, "Location", "best")
            app.axBFactor_a.XLimMode = 'auto';
            app.axBFactor_a.YLimMode = 'auto';
            app.axBFactor_a.Toolbar = axtoolbar(app.axBFactor_a,'default');
            %allargamento spaziale
            plot(app.axSpecBFactor_a, z, s_bFactor, 'k-', "DisplayName", "active pulse");
            legend(app.axSpecBFactor_a, "Location", "best")
            app.axSpecBFactor_a.XLimMode = 'auto';
            app.axSpecBFactor_a.YLimMode = 'auto';
            app.axSpecBFactor_a.Toolbar = axtoolbar(app.axSpecBFactor_a,'default');
           
            
            holdAX('on', app.axFullEvo_a,app.axSpecFullEvo_a);
            app.updateNote("...done.")
            app.lightNote('w');
        end

        % Button pushed function: SaveasPulse1Button_h
        function SaveasPulse1Button_hPushed(app, event)
            app.updatePulse();
            app.updateUi();
            app.pulse_1 = app.pulse;
            app.updateNote("Pulse 1 generated and stored.")
        end

        % Button pushed function: LoadPulse1Button_h
        function LoadPulse1Button_hPushed(app, event)
            app.loadPulse(app.pulse_1);
            app.updateUi();
            app.updateNote("Pulse 1 loaded and generated.")
        end

        % Button pushed function: SaveasPulse2Button_h
        function SaveasPulse2Button_hPushed(app, event)
            app.updatePulse();
            app.updateUi();
            app.pulse_2 = app.pulse;
            app.updateNote("Pulse 2 generated and stored.")
        end

        % Button pushed function: LoadPulse2Button_h
        function LoadPulse2Button_hPushed(app, event)
            app.loadPulse(app.pulse_2);
            app.updateUi();
            app.updateNote("Pulse 2 loaded and generated.")
        end

        % Button pushed function: SaveasPulse3Button_h
        function SaveasPulse3Button_hPushed(app, event)
            app.updatePulse();
            app.updateUi();
            app.pulse_3 = app.pulse;
            app.updateNote("Pulse 3 generated and stored.")
        end

        % Button pushed function: LoadPulse3Button_h
        function LoadPulse3Button_hPushed(app, event)
            app.loadPulse(app.pulse_3);
            app.updateUi();
            app.updateNote("Pulse 3 loaded and generated.")
        end

        % Value changed function: zEditField_a_comp
        function zEditField_a_compValueChanged(app, event)
            app.Slider_compare.Value = app.zEditField_a_comp.Value;
            app.L_c = app.Slider_compare.Value;
        end

        % Value changed function: Slider_compare
        function Slider_compareValueChanged(app, event)
            app.L_c = app.Slider_compare.Value;
        end

        % Value changed function: TODCheckBox_h
        function TODCheckBox_hValueChanged(app, event)
            app.lightNote('y');
            app.TOD_check_box_update(app.TODCheckBox_h);
            app.lightNote('w');
        end

        % Value changed function: TODCheckBox_a
        function TODCheckBox_aValueChanged(app, event)
            app.lightNote('y');
            app.TOD_check_box_update(app.TODCheckBox_a);
            app.lightNote('w');
        end

        % Value changed function: TODCheckBox_s
        function TODCheckBox_sValueChanged(app, event)
            app.lightNote('y');
            app.TOD_check_box_update(app.TODCheckBox_s);
            app.lightNote('w');
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            app.comparePulses();
        end

        % Value changing function: Slider_compare
        function Slider_compareValueChanging(app, event)
            app.zEditField_a_comp.Value = event.Value;
        end

        % Button pushed function: StopComparingButton
        function StopComparingButtonPushed(app, event)
            app.updateAll();
        end

        % Value changed function: zEditField_a
        function zEditField_aValueChanged(app, event)
            app.Slider.Value = app.zEditField_a.Value;
            app.Slider_2.Value = app.zEditField_a.Value;
            app.L= app.Slider_2.Value;
            app.updateUi();
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.L = app.Slider_2.Value;
            app.Slider.Value = app.L;
            app.zEditField_h.Value = app.L;
            app.updateUi();
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.zEditField_a.Value = event.Value;        
        end

        % Button pushed function: PROPAGATE_2Button
        function PROPAGATE_2ButtonPushed(app, event)
            %L'impulso viene fatto propagare fino a distanza L
            %e la sua evoluzione viene rappresentata nella schermata di
            %analisi
            app.updateNote("Calculating, please wait.")
            app.lightNote('y');
            
            %Split step Fourier Method
            SSFM(app.pulse,app.fiber,app.L,app.TOD_c);
            
            %Il colore dei grafici cambia a seconda della distanza di
            %propagazione
            app.plotCounter = app.plotCounter+1;
            redTone = 1/app.fiber.length*app.L;
            color = [redTone,0,0];
            
            app.pulse.plotPulsePower(app.ax1,app.ax2, color,strcat('z = ',num2str(app.L), ' km'));
            app.pulse.plotPulsePower(app.axPow,app.axSpecPow,color,strcat('z = ',num2str(app.L), ' km'));
            app.pulse.plotPulseModPhase(app.axPulseMod,app.axPulsePhase,color,strcat('z = ',num2str(app.L), ' km'), app.uw_c);
            app.pulse.plotPulseFreqChirp(app.axFreqChirp,color,strcat('z = ',num2str(app.L), ' km'));
              
            app.updateNote("Pulse propagated.")
            app.lightNote('w');
        end

        % Value changed function: LogPowerCheckBox_a
        function LogPowerCheckBox_aValueChanged(app, event)
            %La potenza viene rappresentata in scala logaritmica (pag. analisi)
            app.lightNote('y');
            if app.LogPowerCheckBox_a.Value == 1
                app.axPow.YScale           = "log";
                app.axSpecPow.YScale       = "log";
                app.axFullEvo_a.ZScale     = "log";
                app.axSpecFullEvo_a.ZScale = "log";
            else
                app.axPow.YScale           = "lin";
                app.axSpecPow.YScale       = "lin";
                app.axFullEvo_a.ZScale     = "lin";
                app.axSpecFullEvo_a.ZScale = "lin";
            end
            app.lightNote('w');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.updatePulse();
            app.updateUi();
        end

        % Value changed function: P0EditField_s
        function P0EditField_sValueChanged(app, event)
            app.P0 = app.P0EditField_s.Value;
            %imposto P0 -> aggiorno T0
            app.T0 = sqrt(app.N^2 * abs(app.fiber.beta2)/(app.fiber.gamma*app.P0));        
            app.updateNote(strcat("New T0 calculated. T0: ", num2str(app.T0)));
            app.type =  's';
            
            %aggiorno impulso e Ui
            app.updatePulse();
            app.updateUi(); 
            app.updateNote("Soliton generated")
        end

        % Value changed function: T0EditField_s
        function T0EditField_sValueChanged(app, event)
            app.T0 = app.T0EditField_s.Value;
            %imposto T0 -> aggiorno P0
            app.P0 = app.N^2 * abs(app.fiber.beta2)/(app.fiber.gamma*app.T0^2);        
            app.updateNote(strcat("New P0 calculated. P0: ", num2str(app.P0)));
            app.type = 's';
            
            %aggiorno impulso e Ui
            app.updatePulse();
            app.updateUi(); 
            app.updateNote("Soliton generated")
        end

        % Value changed function: CEditField_s
        function CEditField_sValueChanged(app, event)
            app.C = app.CEditField_s.Value;
            app.type = 's';
            
            %aggiorno impulso e Ui
            app.updatePulse();
            app.updateUi(); 
            app.updateNote("Soliton generated")  
        end

        % Value changed function: NEditField_s
        function NEditField_sValueChanged(app, event)
            app.N = app.NEditField_s.Value;
            %imposto N -> aggiorno P0
            app.P0 = app.N^2 * abs(app.fiber.beta2)/(app.fiber.gamma*app.T0^2);    
            app.updateNote(strcat("New P0 calculated. P0: ", num2str(app.P0)));
            app.type = 's';
            
            %aggiorno impulso e Ui
            app.updatePulse();
            app.updateUi();
            app.updateNote("Soliton generated")
        end

        % Value changed function: gammaEditField_s
        function gammaEditField_sValueChanged(app, event)
            app.NonlinearParameterW1kmEditField.Value = app.gammaEditField_s.Value;
            app.updateFiber();
            app.updateUi();
           
        end

        % Value changed function: beta2EditField_s
        function beta2EditField_sValueChanged(app, event)
            app.GVDParameter2ps2kmEditField.Value = app.beta2EditField_s.Value;
            app.updateFiber();
            app.updateUi();
        end

        % Value changed function: xSliderPhase
        function xSliderPhaseValueChanged(app, event)
            app.changeXLim(app.xSliderPhase,app.axPulsePhase); 
        end

        % Value changed function: xSliderFreqChirp
        function xSliderFreqChirpValueChanged(app, event)
            app.changeXLim(app.xSliderFreqChirp,app.axFreqChirp);
        end

        % Value changed function: xSliderSpecPow
        function xSliderSpecPowValueChanged(app, event)
            app.changeXLim(app.xSliderSpecPow,app.axSpecPow);
            app.changeXLim(app.xSliderSpecPow,app.axSpecFullEvo_a);
        end

        % Value changed function: xSliderPow
        function xSliderPowValueChanged(app, event)
            app.changeXLim(app.xSliderPow,app.axPow);
            app.changeXLim(app.xSliderPow,app.axFullEvo_a);
        end

        % Value changed function: xSliderMod
        function xSliderModValueChanged(app, event)
            app.changeXLim(app.xSliderMod,app.axPulseMod);
        end

        % Value changed function: TmaxEditField_h
        function TmaxEditField_hValueChanged(app, event)
            app.Tmax = app.TmaxEditField_h.Value;
        end

        % Value changed function: mEditField_h
        function mEditField_hValueChanged(app, event)
            app.m = app.mEditField_h.Value;            
        end

        % Value changed function: CEditField_h
        function CEditField_hValueChanged(app, event)
            app.C = app.CEditField_h.Value;
        end

        % Value changed function: T0EditField_h
        function T0EditField_hValueChanged(app, event)
            app.T0 = app.T0EditField_h.Value;
        end

        % Value changed function: P0EditField_h
        function P0EditField_hValueChanged(app, event)
            app.P0 = app.P0EditField_h.Value;
            
        end

        % Value changed function: ntEditField_h
        function ntEditField_hValueChanged(app, event)
            app.nt = app.ntEditField_h.Value;
            
        end

        % Button pushed function: PropagateButton_s
        function PropagateButton_sPushed(app, event)
            %Questa funzione avvia la simulazione dell'evoluzione dei
            %solitoni lungo la fibra

            app.updateNote(">>>Simulating soliton evolution...");
            %reset degli assi 
            holdAX('off',app.axPower_s,app.axSpecPower_s);
            holdAX('off',app.axFullEvo_s,app.axBFactor_s);
            holdAX('off',app.axSpecFullEvo_s, app.axSpecBFactor_s);
            
            app.lightNote('y');
            
            %plotto l'impulso di input
            app.updatePulse();
            app.updateNote("...Input pulse showed...");
            holdAX('on',app.axPower_s,app.axSpecPower_s);
            
            %evolvo e plotto l'impulso  di output
            SSFM(app.pulse,app.fiber,app.fiber.length,app.TOD_c);
            app.pulse.plotPulsePower(app.axPower_s,app.axSpecPower_s,'r', strcat('z = ', num2str(app.fiber.length), ' km'));
            app.updateNote("...Output pulse showed...");
            
            %plotto l'evoluzione lungo tutta la fibra:
            app.updateNote("...Calculating full evolution, please wait...")
            [z_fe,powUz_fe,powUz_spec_fe] = evolve(app.pulse,app.fiber,app.TOD_c,11);
            %potenza temporale
            waterfall(app.axFullEvo_s, app.pulse.tau, z_fe,powUz_fe);
            xlabel(app.axFullEvo_s, 'T/T0');
            ylabel(app.axFullEvo_s, 'distance (km)');
            zlabel(app.axFullEvo_s, 'Intensity');
            %potenza spettrale
            waterfall(app.axSpecFullEvo_s,  fftshift(app.pulse.omega)/(2*pi), z_fe,powUz_spec_fe);
            xlabel(app.axSpecFullEvo_s, 'Norm. frequency');
            ylabel(app.axSpecFullEvo_s, 'distance (km)');
            zlabel(app.axSpecFullEvo_s, 'Intensity');
            
            %fattore di allargamento
            holdAX('off',app.axSpecBFactor_s,app.axBFactor_s);
            [z,bFactor,s_bFactor] = getBFactor(app.pulse,app.fiber,app.TOD_c,100);
            %plot del fattore di allargamento temporale
            plot(app.axBFactor_s, z, bFactor, 'k-', "DisplayName", "active pulse");
            legend(app.axBFactor_s, "Location", "best")
            app.axBFactor_s.XLimMode = 'auto';
            app.axBFactor_s.YLimMode = 'auto';
            hold(app.axBFactor_s, "on");
            app.axBFactor_s.Toolbar = axtoolbar(app.axBFactor_s,'default');
            %plot del fattore di allargamento spettrale
            plot(app.axSpecBFactor_s, z, s_bFactor, 'k-', "DisplayName", "active pulse");
            legend(app.axSpecBFactor_s, "Location", "best")
            app.axSpecBFactor_s.XLimMode = 'auto';
            app.axSpecBFactor_s.YLimMode = 'auto';
            hold(app.axSpecBFactor_s, "on");
            app.axSpecBFactor_s.Toolbar = axtoolbar(app.axSpecBFactor_s,'default');
            
            app.updateNote("...done.")
            app.lightNote('w');
        end

        % Button pushed function: GENERATEButton_h
        function GENERATEButton_hPushed(app, event)
            %L'impulso di input viene aggiornato
            app.lightNote('y');
            app.updatePulse;
            app.updateUi;
            app.lightNote('w');
        end

        % Value changed function: LogPowerCheckBox_s
        function LogPowerCheckBox_sValueChanged(app, event)
            %Cambia la scala di rappresentazione della potenza (pag. Solitons)
            app.lightNote('y');
            
            if app.LogPowerCheckBox_s.Value == 1
                %Rappresentazione logaritmica della potenza
                app.axPower_s.ZScale       = "log";
                app.axSpecPower_s.ZScale   = "log";
                app.axFullEvo_s.ZScale     = "log";
                app.axSpecFullEvo_s.ZScale = "log";
            else
                %Rappresentazione lineare della potenza
                app.axPower_s.ZScale       = "lin";
                app.axSpecPower_s.ZScale   = "lin";
                app.axFullEvo_s.ZScale     = "lin";
                app.axSpecFullEvo_s.ZScale = "lin";
            end
            app.lightNote('w');
        end

        % Value changed function: xSliderPow_s
        function xSliderPow_sValueChanged(app, event)
            %Modifico i limiti dell'asse x
            app.changeXLim(app.xSliderPow_s,app.axPower_s);
            app.changeXLim(app.xSliderPow_s,app.axFullEvo_s); 
        end

        % Value changed function: xSliderSpecPow_s
        function xSliderSpecPow_sValueChanged(app, event)
            %Modifico i limiti dell'asse x 
            app.changeXLim(app.xSliderSpecPow_s,app.axSpecPower_s);  
            app.changeXLim(app.xSliderSpecPow_s,app.axSpecFullEvo_s); 
        end

        % Value changed function: unwrapCheckBox
        function unwrapCheckBoxValueChanged(app, event)
            if app.unwrapCheckBox.Value == 1
                app.uw_c = "uw_on";
                app.updateNote("Next pulse phase plot will be unwrapped.")
            else
                app.uw_c = "uw_off";
                app.updateNote("Next pulse phase plot won't be unwrapped.")
            end
        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            app.resizeFont(app.UIFigure,app.FontSizeEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Tooltip = {''};
            app.HomeTab.Title = 'Home';
            app.GridLayout = uigridlayout(app.HomeTab);
            app.ax2 = uiaxes(app.GridLayout);
            title(app.ax2, 'Spectrum')
            xlabel(app.ax2, 'X')
            ylabel(app.ax2, 'Y')
            zlabel(app.ax2, 'Z')
            app.ax1 = uiaxes(app.GridLayout);
            title(app.ax1, 'Pulse ')
            xlabel(app.ax1, 'X')
            ylabel(app.ax1, 'Y')
            zlabel(app.ax1, 'Z')
            app.PulseEvolutionPanel = uipanel(app.GridLayout);
            app.PulseEvolutionPanel.Title = 'Pulse Evolution';
            app.GridLayout7 = uigridlayout(app.PulseEvolutionPanel);
            app.Panel2 = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel2);
            app.PROPAGATEButton = uibutton(app.GridLayout8, 'push');
            app.PROPAGATEButton.ButtonPushedFcn = createCallbackFcn(app, @PROPAGATEButtonPushed, true);
            app.PROPAGATEButton.Text = 'PROPAGATE';
            app.EXPORTButton = uibutton(app.GridLayout8, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.Slider = uislider(app.GridLayout7);
            app.Slider.Limits = [0 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.DispersionLengthL_DkmLabel = uilabel(app.GridLayout7);
            app.DispersionLengthL_DkmLabel.Text = 'Dispersion Length L_D (km): ';
            app.LDValueLabel = uilabel(app.GridLayout7);
            app.LDValueLabel.Text = 'LD Value';
            app.NonlinearLengthL_NLkmLabel = uilabel(app.GridLayout7);
            app.NonlinearLengthL_NLkmLabel.Text = 'Nonlinear Length L_NL (km):';
            app.L_NLValueLabel = uilabel(app.GridLayout7);
            app.L_NLValueLabel.Text = 'L_NL Value';
            app.PropagatetozkmEditFieldLabel = uilabel(app.GridLayout7);
            app.PropagatetozkmEditFieldLabel.Text = 'Propagate to z (km) :';
            app.zEditField_h = uieditfield(app.GridLayout7, 'numeric');
            app.zEditField_h.Limits = [0 Inf];
            app.zEditField_h.ValueChangedFcn = createCallbackFcn(app, @zEditField_hValueChanged, true);
            app.NParameterLabel = uilabel(app.GridLayout7);
            app.NParameterLabel.Text = 'N Parameter :';
            app.NValueLabel = uilabel(app.GridLayout7);
            app.NValueLabel.Text = 'N Value';
            app.TODispersionLengthL_DkmLabel = uilabel(app.GridLayout7);
            app.TODispersionLengthL_DkmLabel.Text = 'TO Dispersion Length L_D'' (km):';
            app.LDTValueLabel = uilabel(app.GridLayout7);
            app.LDTValueLabel.Text = 'LDT Value';
            app.StepLengthkmLabel = uilabel(app.GridLayout7);
            app.StepLengthkmLabel.Text = 'Step Length (km) :';
            app.dzvalueLabel = uilabel(app.GridLayout7);
            app.dzvalueLabel.Text = 'dz value';
            app.StepNumberLabel = uilabel(app.GridLayout7);
            app.StepNumberLabel.Text = 'Step Number :';
            app.stp_nvalLabel = uilabel(app.GridLayout7);
            app.stp_nvalLabel.Text = 'stp_n val';
            app.TODCheckBox_h = uicheckbox(app.GridLayout7);
            app.TODCheckBox_h.ValueChangedFcn = createCallbackFcn(app, @TODCheckBox_hValueChanged, true);
            app.TODCheckBox_h.Tooltip = {'Enable/disable Third Order Dispersion.'};
            app.TODCheckBox_h.Text = 'TOD';
            app.TODCheckBox_h.Value = true;
            app.NotePanel = uipanel(app.GridLayout);
            app.GridLayout14 = uigridlayout(app.NotePanel);
            app.CalculatingLamp_3Label = uilabel(app.GridLayout14);
            app.CalculatingLamp_3Label.Text = 'Calculating';
            app.CalculatingLamp_3 = uilamp(app.GridLayout14);
            app.NoteTextArea = uitextarea(app.GridLayout14);
            app.NoteTextArea.Editable = 'off';
            app.PulseGeneratorPanel = uipanel(app.GridLayout);
            app.PulseGeneratorPanel.Title = 'Pulse Generator';
            app.GridLayout33 = uigridlayout(app.PulseGeneratorPanel);
            app.Panel_4 = uipanel(app.GridLayout33);
            app.GridLayout35 = uigridlayout(app.Panel_4);
            app.TypeDropDown_h = uidropdown(app.GridLayout35);
            app.TypeDropDown_h.Items = {'Super Gaussian', 'Hyperbolic Secant'};
            app.TypeDropDown_h.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_hValueChanged, true);
            app.TypeDropDown_h.Value = 'Hyperbolic Secant';
            app.TypeLabel = uilabel(app.GridLayout35);
            app.TypeLabel.Text = 'Type : ';
            app.T0EditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.T0EditField_h.Limits = [0 Inf];
            app.T0EditField_h.ValueChangedFcn = createCallbackFcn(app, @T0EditField_hValueChanged, true);
            app.T0EditField_h.Tooltip = {'Half-width at 1/e intensity point'};
            app.T0EditField_h.Value = 30;
            app.InputhalfwidthT0psLabel = uilabel(app.GridLayout35);
            app.InputhalfwidthT0psLabel.Text = 'Input half-width T0 (ps) :';
            app.TmaxEditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.TmaxEditField_h.Limits = [0 Inf];
            app.TmaxEditField_h.RoundFractionalValues = 'on';
            app.TmaxEditField_h.ValueChangedFcn = createCallbackFcn(app, @TmaxEditField_hValueChanged, true);
            app.TmaxEditField_h.Value = 32;
            app.WindowhalfwidthTmaxT0Label = uilabel(app.GridLayout35);
            app.WindowhalfwidthTmaxT0Label.Text = 'Window half width Tmax (T0):';
            app.P0EditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.P0EditField_h.Limits = [0 Inf];
            app.P0EditField_h.ValueChangedFcn = createCallbackFcn(app, @P0EditField_hValueChanged, true);
            app.P0EditField_h.Value = 0.111111111111111;
            app.InputpeakpowerP0WLabel = uilabel(app.GridLayout35);
            app.InputpeakpowerP0WLabel.Text = 'Input peak power P0 (W) :';
            app.CEditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.CEditField_h.ValueChangedFcn = createCallbackFcn(app, @CEditField_hValueChanged, true);
            app.ChirpCLabel = uilabel(app.GridLayout35);
            app.ChirpCLabel.Text = 'Chirp C  :';
            app.mEditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.mEditField_h.Limits = [1 Inf];
            app.mEditField_h.RoundFractionalValues = 'on';
            app.mEditField_h.ValueChangedFcn = createCallbackFcn(app, @mEditField_hValueChanged, true);
            app.mEditField_h.Value = 1;
            app.ntEditField_h = uieditfield(app.GridLayout35, 'numeric');
            app.ntEditField_h.Limits = [128 Inf];
            app.ntEditField_h.RoundFractionalValues = 'on';
            app.ntEditField_h.ValueChangedFcn = createCallbackFcn(app, @ntEditField_hValueChanged, true);
            app.ntEditField_h.Value = 1024;
            app.FFTpointspowerof2Label = uilabel(app.GridLayout35);
            app.FFTpointspowerof2Label.Text = 'FFT points [power of 2]: ';
            app.mEditFieldLabel_h = uilabel(app.GridLayout35);
            app.mEditFieldLabel_h.Text = 'Edge sharpness m  :';
            app.Panel_5 = uipanel(app.GridLayout33);
            app.GridLayout34 = uigridlayout(app.Panel_5);
            app.GENERATEButton_h = uibutton(app.GridLayout34, 'push');
            app.GENERATEButton_h.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_hPushed, true);
            app.GENERATEButton_h.Text = 'GENERATE';
            app.SaveasPulse1Button_h = uibutton(app.GridLayout34, 'push');
            app.SaveasPulse1Button_h.ButtonPushedFcn = createCallbackFcn(app, @SaveasPulse1Button_hPushed, true);
            app.SaveasPulse1Button_h.Text = {'Save as'; 'Pulse  1'};
            app.SaveasPulse2Button_h = uibutton(app.GridLayout34, 'push');
            app.SaveasPulse2Button_h.ButtonPushedFcn = createCallbackFcn(app, @SaveasPulse2Button_hPushed, true);
            app.SaveasPulse2Button_h.Text = {'Save as'; 'Pulse  2'};
            app.SaveasPulse3Button_h = uibutton(app.GridLayout34, 'push');
            app.SaveasPulse3Button_h.ButtonPushedFcn = createCallbackFcn(app, @SaveasPulse3Button_hPushed, true);
            app.SaveasPulse3Button_h.Text = {'Save as'; 'Pulse 3'};
            app.LoadPulse1Button_h = uibutton(app.GridLayout34, 'push');
            app.LoadPulse1Button_h.ButtonPushedFcn = createCallbackFcn(app, @LoadPulse1Button_hPushed, true);
            app.LoadPulse1Button_h.Text = {'Load '; 'Pulse 1'};
            app.LoadPulse2Button_h = uibutton(app.GridLayout34, 'push');
            app.LoadPulse2Button_h.ButtonPushedFcn = createCallbackFcn(app, @LoadPulse2Button_hPushed, true);
            app.LoadPulse2Button_h.Text = {'Load'; 'Pulse 2'; ''};
            app.LoadPulse3Button_h = uibutton(app.GridLayout34, 'push');
            app.LoadPulse3Button_h.ButtonPushedFcn = createCallbackFcn(app, @LoadPulse3Button_hPushed, true);
            app.LoadPulse3Button_h.Text = {'Load'; 'Pulse 3'};
            app.FiberTab = uitab(app.TabGroup);
            app.FiberTab.Tooltip = {''};
            app.FiberTab.Title = 'Fiber';
            app.GridLayout2 = uigridlayout(app.FiberTab);
            app.FiberGeneratorPanel = uipanel(app.GridLayout2);
            app.FiberGeneratorPanel.Title = 'Fiber Generator';
            app.GridLayout3 = uigridlayout(app.FiberGeneratorPanel);
            app.CoreRadiusamLabel = uilabel(app.GridLayout3);
            app.CoreRadiusamLabel.Text = 'Core Radius  a (μm) :';
            app.CoreRadiusamEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CoreRadiusamEditField.Limits = [0 Inf];
            app.CoreRadiusamEditField.ValueChangedFcn = createCallbackFcn(app, @CoreRadiusamEditFieldValueChanged, true);
            app.CoreRadiusamEditField.Value = 5;
            app.CladdingRadiusbmLabel = uilabel(app.GridLayout3);
            app.CladdingRadiusbmLabel.Text = 'Cladding Radius b (μm) :';
            app.CladdingRadiusbmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CladdingRadiusbmEditField.Limits = [0 Inf];
            app.CladdingRadiusbmEditField.ValueChangedFcn = createCallbackFcn(app, @CladdingRadiusbmEditFieldValueChanged, true);
            app.CladdingRadiusbmEditField.Value = 62.5;
            app.CoreIndexn1Label = uilabel(app.GridLayout3);
            app.CoreIndexn1Label.Text = 'Core Index n1 :';
            app.CoreIndexn1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.CoreIndexn1EditField.Limits = [0 Inf];
            app.CoreIndexn1EditField.ValueChangedFcn = createCallbackFcn(app, @CoreIndexn1EditFieldValueChanged, true);
            app.CoreIndexn1EditField.Value = 1.459;
            app.CladdingIndexn2Label = uilabel(app.GridLayout3);
            app.CladdingIndexn2Label.Text = 'Cladding Index n2 :';
            app.CladdingIndexn2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.CladdingIndexn2EditField.Limits = [0 Inf];
            app.CladdingIndexn2EditField.ValueChangedFcn = createCallbackFcn(app, @CladdingIndexn2EditFieldValueChanged, true);
            app.CladdingIndexn2EditField.Value = 1.455;
            app.GVDParameter2ps2kmLabel_2 = uilabel(app.GridLayout3);
            app.GVDParameter2ps2kmLabel_2.Text = 'GVD Parameter β2 (ps^-2/km) :';
            app.GVDParameter2ps2kmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GVDParameter2ps2kmEditField.ValueChangedFcn = createCallbackFcn(app, @GVDParameter2ps2kmEditFieldValueChanged, true);
            app.GVDParameter2ps2kmEditField.Value = -20;
            app.TODParameter3ps3kmLabel = uilabel(app.GridLayout3);
            app.TODParameter3ps3kmLabel.Text = 'TOD Parameter β3 (ps^-3/km) :';
            app.TODParameter3ps3kmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TODParameter3ps3kmEditField.ValueChangedFcn = createCallbackFcn(app, @TODParameter3ps3kmEditFieldValueChanged, true);
            app.TODParameter3ps3kmEditField.Value = 10;
            app.NonlinearParameterW1kmLabel = uilabel(app.GridLayout3);
            app.NonlinearParameterW1kmLabel.Text = 'Nonlinear Parameter γ (W^-1/km) :';
            app.NonlinearParameterW1kmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NonlinearParameterW1kmEditField.ValueChangedFcn = createCallbackFcn(app, @NonlinearParameterW1kmEditFieldValueChanged, true);
            app.NonlinearParameterW1kmEditField.Value = 0.2;
            app.AttenuationdBkmLabel = uilabel(app.GridLayout3);
            app.AttenuationdBkmLabel.Text = 'Attenuation α (dB/km) :';
            app.AttenuationdBkmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AttenuationdBkmEditField.Limits = [0 Inf];
            app.AttenuationdBkmEditField.ValueChangedFcn = createCallbackFcn(app, @AttenuationdBkmEditFieldValueChanged, true);
            app.DParameterpskmnmLabel = uilabel(app.GridLayout3);
            app.DParameterpskmnmLabel.Text = 'D Parameter (ps/km-nm) :';
            app.DParameterpskmnmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DParameterpskmnmEditField.ValueChangedFcn = createCallbackFcn(app, @DParameterpskmnmEditFieldValueChanged, true);
            app.UsedWavelengthmLabel = uilabel(app.GridLayout3);
            app.UsedWavelengthmLabel.Text = 'Used Wavelength λ (μm) :';
            app.UsedWavelengthmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.UsedWavelengthmEditField.Limits = [0 Inf];
            app.UsedWavelengthmEditField.ValueChangedFcn = createCallbackFcn(app, @UsedWavelengthmEditFieldValueChanged, true);
            app.UsedWavelengthmEditField.Value = 1.55;
            app.FiberLengthkmLabel = uilabel(app.GridLayout3);
            app.FiberLengthkmLabel.Text = 'Fiber Length (km) :';
            app.FiberLengthkmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FiberLengthkmEditField.Limits = [0 Inf];
            app.FiberLengthkmEditField.ValueChangedFcn = createCallbackFcn(app, @FiberLengthkmEditFieldValueChanged, true);
            app.FiberLengthkmEditField.Value = 200;
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.k0m1Label = uilabel(app.GridLayout4);
            app.k0m1Label.Text = 'k0 (μm^-1) :';
            app.k0valueLabel = uilabel(app.GridLayout4);
            app.k0valueLabel.Text = 'k0 value';
            app.VParameterLabel = uilabel(app.GridLayout4);
            app.VParameterLabel.Text = 'V Parameter :';
            app.RelativeIndexDifferenceLabel = uilabel(app.GridLayout4);
            app.RelativeIndexDifferenceLabel.Text = 'Relative Index Difference  Δ :';
            app.VParameterValueLabel = uilabel(app.GridLayout4);
            app.VParameterValueLabel.Text = 'V Parameter Value';
            app.DeltavalueLabel = uilabel(app.GridLayout4);
            app.DeltavalueLabel.Text = 'Delta value';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Tooltip = {''};
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.ButtonDownFcn = createCallbackFcn(app, @AnalysisTabButtonDown, true);
            app.GridLayout9 = uigridlayout(app.AnalysisTab);
            app.FullEvolutionPanel_a = uipanel(app.GridLayout9);
            app.FullEvolutionPanel_a.Title = 'Full Evolution';
            app.GridLayout10 = uigridlayout(app.FullEvolutionPanel_a);
            app.CalculateButton_a = uibutton(app.GridLayout10, 'push');
            app.CalculateButton_a.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_aPushed, true);
            app.CalculateButton_a.Text = 'Calculate';
            app.FullEvoTabGroup_a = uitabgroup(app.GridLayout10);
            app.FullEvoTab_a = uitab(app.FullEvoTabGroup_a);
            app.FullEvoTab_a.Title = 'Pulse';
            app.GridLayout39 = uigridlayout(app.FullEvoTab_a);
            app.axFullEvo_a = uiaxes(app.GridLayout39);
            title(app.axFullEvo_a, 'Intensity Evolution')
            xlabel(app.axFullEvo_a, 'T/T0')
            ylabel(app.axFullEvo_a, 'distance (km)')
            zlabel(app.axFullEvo_a, 'Intensity')
            app.Panel_7 = uipanel(app.GridLayout39);
            app.GridLayout42 = uigridlayout(app.Panel_7);
            app.axBFactor_a = uiaxes(app.GridLayout42);
            title(app.axBFactor_a, 'Temporal Broadening')
            xlabel(app.axBFactor_a, 'distance (km) ')
            ylabel(app.axBFactor_a, 'Broadening Factor')
            zlabel(app.axBFactor_a, 'Z')
            app.SpecFullEvoTab_a = uitab(app.FullEvoTabGroup_a);
            app.SpecFullEvoTab_a.Title = 'Spectrum';
            app.GridLayout40 = uigridlayout(app.SpecFullEvoTab_a);
            app.axSpecFullEvo_a = uiaxes(app.GridLayout40);
            title(app.axSpecFullEvo_a, 'Intensity Evolution')
            xlabel(app.axSpecFullEvo_a, 'Norm. frequency')
            ylabel(app.axSpecFullEvo_a, 'distance (km)')
            zlabel(app.axSpecFullEvo_a, 'Intensity')
            app.Panel_8 = uipanel(app.GridLayout40);
            app.GridLayout43 = uigridlayout(app.Panel_8);
            app.axSpecBFactor_a = uiaxes(app.GridLayout43);
            title(app.axSpecBFactor_a, 'Spectral Broadening')
            xlabel(app.axSpecBFactor_a, 'distance (km) ')
            ylabel(app.axSpecBFactor_a, 'Broadening Factor')
            zlabel(app.axSpecBFactor_a, 'Z')
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.ComparePulsesPanel = uipanel(app.GridLayout11);
            app.ComparePulsesPanel.Title = 'Compare Pulses';
            app.GridLayout12 = uigridlayout(app.ComparePulsesPanel);
            app.Pulse1CheckBox = uicheckbox(app.GridLayout12);
            app.Pulse1CheckBox.Text = 'Pulse 1';
            app.Pulse1CheckBox.Value = true;
            app.Pulse2CheckBox = uicheckbox(app.GridLayout12);
            app.Pulse2CheckBox.Text = 'Pulse 2';
            app.Pulse2CheckBox.Value = true;
            app.Pulse3CheckBox = uicheckbox(app.GridLayout12);
            app.Pulse3CheckBox.Text = 'Pulse 3';
            app.CompareButton = uibutton(app.GridLayout12, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Tooltip = {'Pulses will be normalized with respect to Pulse 1.'};
            app.CompareButton.Text = 'Compare';
            app.Slider_compare = uislider(app.GridLayout12);
            app.Slider_compare.ValueChangedFcn = createCallbackFcn(app, @Slider_compareValueChanged, true);
            app.Slider_compare.ValueChangingFcn = createCallbackFcn(app, @Slider_compareValueChanging, true);
            app.zkmLabel = uilabel(app.GridLayout12);
            app.zkmLabel.Text = 'z (km):';
            app.zEditField_a_comp = uieditfield(app.GridLayout12, 'numeric');
            app.zEditField_a_comp.Limits = [0 Inf];
            app.zEditField_a_comp.ValueChangedFcn = createCallbackFcn(app, @zEditField_a_compValueChanged, true);
            app.StopComparingButton = uibutton(app.GridLayout12, 'push');
            app.StopComparingButton.ButtonPushedFcn = createCallbackFcn(app, @StopComparingButtonPushed, true);
            app.StopComparingButton.Text = 'Stop Comparing';
            app.PropagateactivepulsePanel = uipanel(app.GridLayout11);
            app.PropagateactivepulsePanel.Title = 'Propagate active pulse';
            app.GridLayout15 = uigridlayout(app.PropagateactivepulsePanel);
            app.Slider_2 = uislider(app.GridLayout15);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 14.7198573857235;
            app.zkmEditFieldLabel = uilabel(app.GridLayout15);
            app.zkmEditFieldLabel.Text = 'z (km):';
            app.zEditField_a = uieditfield(app.GridLayout15, 'numeric');
            app.zEditField_a.Limits = [0 Inf];
            app.zEditField_a.ValueChangedFcn = createCallbackFcn(app, @zEditField_aValueChanged, true);
            app.PROPAGATE_2Button = uibutton(app.GridLayout15, 'push');
            app.PROPAGATE_2Button.ButtonPushedFcn = createCallbackFcn(app, @PROPAGATE_2ButtonPushed, true);
            app.PROPAGATE_2Button.Text = 'Propagate';
            app.ResetButton = uibutton(app.GridLayout15, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NoteTextArea_2 = uitextarea(app.GridLayout9);
            app.NoteTextArea_2.Editable = 'off';
            app.Panel_2 = uipanel(app.GridLayout9);
            app.GridLayout16 = uigridlayout(app.Panel_2);
            app.TODCheckBox_a = uicheckbox(app.GridLayout16);
            app.TODCheckBox_a.ValueChangedFcn = createCallbackFcn(app, @TODCheckBox_aValueChanged, true);
            app.TODCheckBox_a.Tooltip = {'Enable/disable Third Order Dispersion.'};
            app.TODCheckBox_a.Text = 'TOD';
            app.TODCheckBox_a.Value = true;
            app.LogPowerCheckBox_a = uicheckbox(app.GridLayout16);
            app.LogPowerCheckBox_a.ValueChangedFcn = createCallbackFcn(app, @LogPowerCheckBox_aValueChanged, true);
            app.LogPowerCheckBox_a.Text = 'Log Power';
            app.GridLayout25 = uigridlayout(app.GridLayout16);
            app.CalculatingLampLabel = uilabel(app.GridLayout25);
            app.CalculatingLampLabel.Text = 'Calculating';
            app.CalculatingLamp = uilamp(app.GridLayout25);
            app.PulseModPanel = uipanel(app.GridLayout9);
            app.GridLayout19 = uigridlayout(app.PulseModPanel);
            app.axPulseMod = uiaxes(app.GridLayout19);
            title(app.axPulseMod, 'Title')
            xlabel(app.axPulseMod, 'X')
            ylabel(app.axPulseMod, 'Y')
            zlabel(app.axPulseMod, 'Z')
            app.xSliderMod = uislider(app.GridLayout19);
            app.xSliderMod.ValueChangedFcn = createCallbackFcn(app, @xSliderModValueChanged, true);
            app.xSliderMod.Tag = 'xSlider';
            app.xSliderMod.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.PulsePowerPanel = uipanel(app.GridLayout9);
            app.GridLayout20 = uigridlayout(app.PulsePowerPanel);
            app.axPow = uiaxes(app.GridLayout20);
            title(app.axPow, 'Title')
            xlabel(app.axPow, 'X')
            ylabel(app.axPow, 'Y')
            zlabel(app.axPow, 'Z')
            app.xSliderPow = uislider(app.GridLayout20);
            app.xSliderPow.ValueChangedFcn = createCallbackFcn(app, @xSliderPowValueChanged, true);
            app.xSliderPow.Tag = 'xSlider';
            app.xSliderPow.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.xSliderPow.Value = 65.2907326956776;
            app.PulseSpecPowPanel = uipanel(app.GridLayout9);
            app.GridLayout21 = uigridlayout(app.PulseSpecPowPanel);
            app.axSpecPow = uiaxes(app.GridLayout21);
            title(app.axSpecPow, 'Title')
            xlabel(app.axSpecPow, 'X')
            ylabel(app.axSpecPow, 'Y')
            zlabel(app.axSpecPow, 'Z')
            app.xSliderSpecPow = uislider(app.GridLayout21);
            app.xSliderSpecPow.ValueChangedFcn = createCallbackFcn(app, @xSliderSpecPowValueChanged, true);
            app.xSliderSpecPow.Tag = 'xSlider';
            app.xSliderSpecPow.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.xSliderSpecPow.Value = 28.4690759229871;
            app.PulsePhasePanel = uipanel(app.GridLayout9);
            app.GridLayout22 = uigridlayout(app.PulsePhasePanel);
            app.axPulsePhase = uiaxes(app.GridLayout22);
            title(app.axPulsePhase, 'Title')
            xlabel(app.axPulsePhase, 'X')
            ylabel(app.axPulsePhase, 'Y')
            zlabel(app.axPulsePhase, 'Z')
            app.xSliderPhase = uislider(app.GridLayout22);
            app.xSliderPhase.ValueChangedFcn = createCallbackFcn(app, @xSliderPhaseValueChanged, true);
            app.xSliderPhase.Tag = 'xSlider';
            app.xSliderPhase.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.unwrapCheckBox = uicheckbox(app.GridLayout22);
            app.unwrapCheckBox.ValueChangedFcn = createCallbackFcn(app, @unwrapCheckBoxValueChanged, true);
            app.unwrapCheckBox.Tooltip = {'Enable to eliminate discontinuities by adding multiple of ±2π; note that the central value of the array will be the 0 referement.'};
            app.unwrapCheckBox.Text = 'unwrap';
            app.unwrapCheckBox.Value = true;
            app.PulseFreqChirpPanel = uipanel(app.GridLayout9);
            app.GridLayout23 = uigridlayout(app.PulseFreqChirpPanel);
            app.axFreqChirp = uiaxes(app.GridLayout23);
            title(app.axFreqChirp, 'Chirping')
            xlabel(app.axFreqChirp, 'X')
            ylabel(app.axFreqChirp, 'Y')
            zlabel(app.axFreqChirp, 'Z')
            app.xSliderFreqChirp = uislider(app.GridLayout23);
            app.xSliderFreqChirp.ValueChangedFcn = createCallbackFcn(app, @xSliderFreqChirpValueChanged, true);
            app.xSliderFreqChirp.Tag = 'xSlider';
            app.xSliderFreqChirp.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.SolitonsTab = uitab(app.TabGroup);
            app.SolitonsTab.Tooltip = {''};
            app.SolitonsTab.Title = 'Solitons';
            app.GridLayout17 = uigridlayout(app.SolitonsTab);
            app.SolitonOrderCalculatorPanel = uipanel(app.GridLayout17);
            app.SolitonOrderCalculatorPanel.Title = 'Soliton Order Calculator';
            app.GridLayout18 = uigridlayout(app.SolitonOrderCalculatorPanel);
            app.NonlinearParametrerW1kmLabel = uilabel(app.GridLayout18);
            app.NonlinearParametrerW1kmLabel.Text = 'Nonlinear Parametrer γ (W^-1/km) :';
            app.gammaEditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.gammaEditField_s.ValueChangedFcn = createCallbackFcn(app, @gammaEditField_sValueChanged, true);
            app.GVDParameter2ps2kmLabel = uilabel(app.GridLayout18);
            app.GVDParameter2ps2kmLabel.Text = 'GVD Parameter β2 (ps^2/km) :';
            app.beta2EditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.beta2EditField_s.ValueChangedFcn = createCallbackFcn(app, @beta2EditField_sValueChanged, true);
            app.NEditFieldLabel = uilabel(app.GridLayout18);
            app.NEditFieldLabel.Text = 'N :';
            app.NEditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.NEditField_s.ValueChangedFcn = createCallbackFcn(app, @NEditField_sValueChanged, true);
            app.NEditField_s.Tooltip = {'Insert an integer value to generate solitons.'};
            app.NEditField_s.Value = 1;
            app.P0WLabel = uilabel(app.GridLayout18);
            app.P0WLabel.Text = 'P0 (W) :';
            app.P0EditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.P0EditField_s.Limits = [0 Inf];
            app.P0EditField_s.ValueChangedFcn = createCallbackFcn(app, @P0EditField_sValueChanged, true);
            app.T0psLabel_2 = uilabel(app.GridLayout18);
            app.T0psLabel_2.Text = 'T0 (ps) :';
            app.T0EditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.T0EditField_s.Limits = [0 Inf];
            app.T0EditField_s.ValueChangedFcn = createCallbackFcn(app, @T0EditField_sValueChanged, true);
            app.DispersionLengthL_DkmLabel_2 = uilabel(app.GridLayout18);
            app.DispersionLengthL_DkmLabel_2.Text = 'Dispersion Length L_D (km) :';
            app.L_DEditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.L_DEditField_s.Editable = 'off';
            app.NonlinearLengthL_NLkmLabel_2 = uilabel(app.GridLayout18);
            app.NonlinearLengthL_NLkmLabel_2.Text = 'Nonlinear Length L_NL (km ):';
            app.L_NLEditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.L_NLEditField_s.Editable = 'off';
            app.CEditFieldLabel = uilabel(app.GridLayout18);
            app.CEditFieldLabel.Text = 'C :';
            app.CEditField_s = uieditfield(app.GridLayout18, 'numeric');
            app.CEditField_s.ValueChangedFcn = createCallbackFcn(app, @CEditField_sValueChanged, true);
            app.NoteTextArea_s = uitextarea(app.GridLayout17);
            app.NoteTextArea_s.Editable = 'off';
            app.SolitonEvolutionPanel = uipanel(app.GridLayout17);
            app.SolitonEvolutionPanel.Title = 'Soliton Evolution';
            app.GridLayout_sol_evo = uigridlayout(app.SolitonEvolutionPanel);
            app.PropagateButton_s = uibutton(app.GridLayout_sol_evo, 'push');
            app.PropagateButton_s.ButtonPushedFcn = createCallbackFcn(app, @PropagateButton_sPushed, true);
            app.PropagateButton_s.Text = 'Propagate';
            app.FullEvoPanel_s = uipanel(app.GridLayout_sol_evo);
            app.GridLayout28 = uigridlayout(app.FullEvoPanel_s);
            app.axFullEvo_s = uiaxes(app.GridLayout28);
            title(app.axFullEvo_s, 'Temporal Evolution')
            xlabel(app.axFullEvo_s, 'T/T0')
            ylabel(app.axFullEvo_s, 'distance (km)')
            zlabel(app.axFullEvo_s, 'Intensity')
            app.PowerPanel_s = uipanel(app.GridLayout_sol_evo);
            app.GridLayout29 = uigridlayout(app.PowerPanel_s);
            app.axPower_s = uiaxes(app.GridLayout29);
            title(app.axPower_s, 'Pulse')
            xlabel(app.axPower_s, {'T/T0'; ''})
            ylabel(app.axPower_s, 'Normalized Power')
            zlabel(app.axPower_s, 'Z')
            app.xSliderPow_s = uislider(app.GridLayout29);
            app.xSliderPow_s.ValueChangedFcn = createCallbackFcn(app, @xSliderPow_sValueChanged, true);
            app.xSliderPow_s.Tag = 'xSlider';
            app.xSliderPow_s.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.xSliderPow_s.Value = 80;
            app.SpecPowerPanel_s = uipanel(app.GridLayout_sol_evo);
            app.GridLayout30 = uigridlayout(app.SpecPowerPanel_s);
            app.axSpecPower_s = uiaxes(app.GridLayout30);
            title(app.axSpecPower_s, 'Spectrum')
            xlabel(app.axSpecPower_s, 'Norm. frequency')
            ylabel(app.axSpecPower_s, 'Spectral Power')
            zlabel(app.axSpecPower_s, 'Z')
            app.xSliderSpecPow_s = uislider(app.GridLayout30);
            app.xSliderSpecPow_s.ValueChangedFcn = createCallbackFcn(app, @xSliderSpecPow_sValueChanged, true);
            app.xSliderSpecPow_s.Tag = 'xSlider';
            app.xSliderSpecPow_s.Tooltip = {'Slide to change the display limits of the x-axis.'};
            app.xSliderSpecPow_s.Value = 65.2907326956776;
            app.SpecFullEvoPanel_s = uipanel(app.GridLayout_sol_evo);
            app.GridLayout31 = uigridlayout(app.SpecFullEvoPanel_s);
            app.axSpecFullEvo_s = uiaxes(app.GridLayout31);
            title(app.axSpecFullEvo_s, {'Spectral Evolution'; ''})
            xlabel(app.axSpecFullEvo_s, 'Norm. frequency')
            ylabel(app.axSpecFullEvo_s, 'Distance (km)')
            zlabel(app.axSpecFullEvo_s, 'Intensity')
            app.Panel_3 = uipanel(app.GridLayout_sol_evo);
            app.GridLayout32 = uigridlayout(app.Panel_3);
            app.axBFactor_s = uiaxes(app.GridLayout32);
            title(app.axBFactor_s, 'Temporal Broadening')
            xlabel(app.axBFactor_s, 'Distance (km)')
            ylabel(app.axBFactor_s, 'B. Factor')
            zlabel(app.axBFactor_s, 'Z')
            app.GridLayout36 = uigridlayout(app.GridLayout_sol_evo);
            app.TODCheckBox_s = uicheckbox(app.GridLayout36);
            app.TODCheckBox_s.ValueChangedFcn = createCallbackFcn(app, @TODCheckBox_sValueChanged, true);
            app.TODCheckBox_s.Tooltip = {'Enable/disable Third Order Dispersion.'};
            app.TODCheckBox_s.Text = 'TOD';
            app.LogPowerCheckBox_s = uicheckbox(app.GridLayout36);
            app.LogPowerCheckBox_s.ValueChangedFcn = createCallbackFcn(app, @LogPowerCheckBox_sValueChanged, true);
            app.LogPowerCheckBox_s.Text = 'Log Power';
            app.Panel_9 = uipanel(app.GridLayout_sol_evo);
            app.GridLayout44 = uigridlayout(app.Panel_9);
            app.axSpecBFactor_s = uiaxes(app.GridLayout44);
            title(app.axSpecBFactor_s, 'Spectral Broadening')
            xlabel(app.axSpecBFactor_s, 'Distance (km)')
            ylabel(app.axSpecBFactor_s, 'B. Factor')
            zlabel(app.axSpecBFactor_s, 'Z')
            app.TextArea_2 = uitextarea(app.GridLayout17);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Insert the desired integer soliton order N:  P0 and T0 will be calculated.'; 'γ  and β2 must be nonzero to generate solitons. '; 'An Hyperbolic Secant pulse will be generated to simulate an optical soliton. '; 'Click on "Propagate" to analyze soliton evolution.'};
            app.GridLayout26 = uigridlayout(app.GridLayout17);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.CalculatingLamp_2Label = uilabel(app.GridLayout27);
            app.CalculatingLamp_2Label.Text = 'Calculating';
            app.CalculatingLamp_2 = uilamp(app.GridLayout27);
            app.GuideTab = uitab(app.TabGroup);
            app.GuideTab.Title = 'Guide';
            app.GridLayout13 = uigridlayout(app.GuideTab);
            app.FiberPanel = uipanel(app.GridLayout13);
            app.FiberPanel.Title = 'Fiber';
            app.GridLayout46 = uigridlayout(app.FiberPanel);
            app.TextArea_3 = uitextarea(app.GridLayout46);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'In this section it is possible to configure physical properties of a silica fiber. '};
            app.AnalysisPanel = uipanel(app.GridLayout13);
            app.AnalysisPanel.Title = 'Analysis';
            app.GridLayout47 = uigridlayout(app.AnalysisPanel);
            app.TextArea = uitextarea(app.GridLayout47);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'In this section different graphics are shown. '; '-Using the respective panels, user can visualize the evolution of the last generated pulse or compare up to three different pulse. '; '-Under most axes a slider allows the user to change the limits of the x axes.'; '-When a single pulse (active pulse) is analyzed, it is possible to visualize its power full evolution along the fiber. User must click the "Calculate" button to see pulse full evolution  and time broadening factor.'; '-When three pulses are compared, pulse_2 and pulse_3 are normalized with respect to pulse_1 and power full evolution is not shown.'; ''};
            app.HomePanel = uipanel(app.GridLayout13);
            app.HomePanel.Title = 'Home';
            app.GridLayout45 = uigridlayout(app.HomePanel);
            app.TextArea_4 = uitextarea(app.GridLayout45);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'In this section super-Gaussian pulses  or Hyperbolic Secant pulses can be generated by setting initial'; 'properties. The "GENERATE" button updates the active pulse and plots its normalized power.'; 'Up to three pulses can be stored and loaded for later use. '; 'In the "Pulse Propagation"  panel the pulse can be propagated along the fiber to the selected distance z. '; 'After each propagation, power axes are updated. '; 'Clik "GENERATE" to reset.'};
            app.SolitonsPanel = uipanel(app.GridLayout13);
            app.SolitonsPanel.Title = 'Solitons';
            app.GridLayout48 = uigridlayout(app.SolitonsPanel);
            app.TextArea_5 = uitextarea(app.GridLayout48);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'In this section it is possible to set the desired integer soltiton order parameter (N) and P0 will be calculated accordingly. '; 'It is also possiblo to edit fiber properties γ and β2. '; 'A change in T0 will produce a change in P0 to mantain N constant and vice versa.'; 'After these pulse properties are configurated, an Hyperbolic Secant pulse will be generated. '; 'Soliton evolution can be analyzed in "Soliton Evolution" panel or in "Analysis" page. '; 'The analyzed soliton can be stored or exported in "Home" page.'};
            app.InfoPanel = uipanel(app.GridLayout13);
            app.InfoPanel.Title = 'Info ';
            app.GridLayout49 = uigridlayout(app.InfoPanel);
            app.TextArea_6 = uitextarea(app.GridLayout49);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'This program is a thesis project by Vallar Enrico. '; 'The thesis supervisors are prof. M. Santagiustina and prof. A. Galtarossa.'; 'University of Padua, DEI, Bachelor''s degree in electronic engineering.'; ''; 'The main function used in this program is based on the Split Step Fourier Method presented in "Nonlinear Fiber Optics" by Govind P. Agrawal.'; ''};
            app.FontSizeEditFieldLabel = uilabel(app.GridLayout13);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.GridLayout13, 'numeric');
            app.FontSizeEditField.Limits = [0 24];
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.FontSizeEditField.Value = 12;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulseEvolution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end